classdef input_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ContinueButton               matlab.ui.control.Button
        NumOfIntermediates           matlab.ui.control.DropDown
        NumOfIntermediatesLabel      matlab.ui.control.Label
        Input14                      matlab.ui.control.EditField
        Label14                      matlab.ui.control.Label
        Input13                      matlab.ui.control.EditField
        Label13                      matlab.ui.control.Label
        Input12                      matlab.ui.control.EditField
        Label12                      matlab.ui.control.Label
        Input11                      matlab.ui.control.EditField
        Label11                      matlab.ui.control.Label
        Input10                      matlab.ui.control.EditField
        Label10                      matlab.ui.control.Label
        Input9                       matlab.ui.control.EditField
        Label9                       matlab.ui.control.Label
        Input8                       matlab.ui.control.EditField
        Label8                       matlab.ui.control.Label
        Input7                       matlab.ui.control.EditField
        Label7                       matlab.ui.control.Label
        Input6                       matlab.ui.control.EditField
        Label6                       matlab.ui.control.Label
        Input5                       matlab.ui.control.EditField
        Label5                       matlab.ui.control.Label
        Input4                       matlab.ui.control.EditField
        Label4                       matlab.ui.control.Label
        Input3                       matlab.ui.control.EditField
        Label3                       matlab.ui.control.Label
        Input2                       matlab.ui.control.EditField
        Label2                       matlab.ui.control.Label
        Input1                       matlab.ui.control.EditField
        Label1                       matlab.ui.control.Label
        ModeOfTransport              matlab.ui.control.ListBox
        ModeOfTransportLabel         matlab.ui.control.Label
        OptimizationTypeSwitch       matlab.ui.control.Switch
        OptimizationTypeSwitchLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumOfIntermediates
        function intermediate_visability(app, event)
            %If the number of intermediates changes
            value = app.NumOfIntermediates.Value;

            %Updating Labels on every text box
            app.Label4.Text = "3rd Intermediate";
            app.Label5.Text = "4th Intermediate";
            app.Label6.Text = "5th Intermediate";
            app.Label7.Text = "6th Intermediate";
            app.Label8.Text = "7th Intermediate";
            app.Label9.Text = "8th Intermediate";
            app.Label10.Text = "9th Intermediate";
            app.Label11.Text = "10th Intermediate";
            app.Label12.Text = "11th Intermediate";
            app.Label13.Text = "12th Intermediate";

            %Setting the last visable text box to say "destination"
            labelName = "Label" + num2str(str2double(value) + 2);
            app.(labelName).Text = "Destination";
            
            %Toggling visability for every effected text box and label
            for i = 5:14
                inputName = "Input" + num2str(i);
                labelName = "Label" + num2str(i);
                if i > (str2double(value) + 2)
                    app.(inputName).Visible = "off";
                    app.(labelName).Visible = "off";
                else
                    app.(inputName).Visible = "on";
                    app.(labelName).Visible = "on";
                end
            end

        end

        % Value changed function: OptimizationTypeSwitch
        function type_optimized(app, event)
            %If the type of optimization switches
            value = app.OptimizationTypeSwitch.Value;

            %Changing the available modes of transport for selection
            if strcmp(value, app.OptimizationTypeSwitch.Items(2)) %Essentially: if on
                app.ModeOfTransport.Items = ["Driving", "Walking", "Biking"];
            else
                app.ModeOfTransport.Items = ["Driving (Traffic Aware)", "Driving (Traffic Unaware)", "Walking", "Biking"];
            end

        end

        % Button pushed function: ContinueButton
        function input_complete(app, event)
            %When close button is hit

            %Defining variables
            numOfIntermediates = str2double(app.NumOfIntermediates.Value);
            warningThrown = false;

            %Finding if the program should optimize for distance or travel
            %time
            if strcmp(app.OptimizationTypeSwitch.Value, app.OptimizationTypeSwitch.Items(2))
                optimizeForDistance = true;
            else
                optimizeForDistance = false;
            end

            %Checking if an error should be thrown because no mode of
            %transport is selected
            if isempty(app.ModeOfTransport.Value)
                warningFig = uifigure('Position',[100 100 500 250]);
                uialert(warningFig,'Please Select a Mode of Transport','Error: No Input Selected', 'CloseFcn', @(~, ~)close(warningFig))
                warningThrown = true;
            else
                %Finding what mode of transport is selected
                switch app.ModeOfTransport.Value
                    case "Driving (Traffic Aware)"
                        selectedModeOfTransport = "DRIVE";
                    case "Driving (Traffic Unaware)"
                        selectedModeOfTransport = "driving";
                    case "Driving"
                        selectedModeOfTransport = "driving";
                    case "Walking"
                        selectedModeOfTransport = "walking";
                    case "Biking"
                        selectedModeOfTransport = "bicycling";
                end
            end

            %Checking if any visable text boxes are missing inputs 
            %(besides the destination one)
            locationSearchEmptyIndex = zeros(numOfIntermediates, 1);
            mapsSearchMatrix = strings(numOfIntermediates + 2, 1);
            for i = 1:numOfIntermediates + 1
                inputName = "Input" + num2str(i);
                textBox = app.(inputName).Value;
                locationSearchEmptyIndex(i) = isempty(textBox);
            end

            if sum(locationSearchEmptyIndex) > 0
                %Throwing an error if any location text boxes are missing
                %inputs (besides the destination one)
                warningFig = uifigure('Position',[100 100 500 250]);
                uialert(warningFig,["Please Fill out all Location Search Boxes"; "(Destination can be left blank)"],'Error: Missing Inputs', 'CloseFcn', @(~, ~)close(warningFig))
                warningThrown = true;
            else
                %Finding the user input for each location
                for i = 1:numOfIntermediates + 2
                    inputName = "Input" + num2str(i);
                    mapsSearchMatrix(i) = string(app.(inputName).Value);
                end
            end

            %Saving data to the input.mat if no warning is thrown
            if warningThrown == false
                inputTime = datetime('now', 'TimeZone', 'UTC', 'Format', 'yyyyMMdd.hhmmss');
                save('apps/referenced_files/input.mat', "numOfIntermediates", "optimizeForDistance", "selectedModeOfTransport", "mapsSearchMatrix", "inputTime")
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptimizationTypeSwitchLabel = uilabel(app.UIFigure);
            app.OptimizationTypeSwitchLabel.Text = 'What do you want to Optimize for?';
            app.OptimizationTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.OptimizationTypeSwitch.Items = {'Optimize for Travel Time', 'Optimize for Distance Traveled'};
            app.OptimizationTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @type_optimized, true);
            app.OptimizationTypeSwitch.Value = 'Optimize for Travel Time';
            app.ModeOfTransportLabel = uilabel(app.UIFigure);
            app.ModeOfTransportLabel.Text = 'What Mode of Transport will you be Using?';
            app.ModeOfTransport = uilistbox(app.UIFigure);
            app.ModeOfTransport.Items = {'Driving (Traffic Aware)', 'Driving (Traffic Unaware)', 'Walking', 'Biking'};
            app.ModeOfTransport.Value = {};
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = 'Origin';
            app.Input1 = uieditfield(app.UIFigure, 'text');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = '1st Intermediate';
            app.Input2 = uieditfield(app.UIFigure, 'text');
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = '2nd Intermediate';
            app.Input3 = uieditfield(app.UIFigure, 'text');
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = '3rd Intermediate';
            app.Input4 = uieditfield(app.UIFigure, 'text');
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = '4th Intermediate';
            app.Input5 = uieditfield(app.UIFigure, 'text');
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = '5th Intermediate';
            app.Input6 = uieditfield(app.UIFigure, 'text');
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = '6th Intermediate';
            app.Input7 = uieditfield(app.UIFigure, 'text');
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = '7th Intermediate';
            app.Input8 = uieditfield(app.UIFigure, 'text');
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = ' 8th Intermediate';
            app.Input9 = uieditfield(app.UIFigure, 'text');
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = '9th Intermediate';
            app.Input10 = uieditfield(app.UIFigure, 'text');
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = '10th Intermediate';
            app.Input11 = uieditfield(app.UIFigure, 'text');
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Text = '11th Intermediate';
            app.Input12 = uieditfield(app.UIFigure, 'text');
            app.Label13 = uilabel(app.UIFigure);
            app.Label13.Text = '12th Intermediate';
            app.Input13 = uieditfield(app.UIFigure, 'text');
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Text = 'Destination';
            app.Input14 = uieditfield(app.UIFigure, 'text');
            app.NumOfIntermediatesLabel = uilabel(app.UIFigure);
            app.NumOfIntermediatesLabel.Text = 'How many Intermediate Destinations do you have? ';
            app.NumOfIntermediates = uidropdown(app.UIFigure);
            app.NumOfIntermediates.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.NumOfIntermediates.ValueChangedFcn = createCallbackFcn(app, @intermediate_visability, true);
            app.NumOfIntermediates.Value = '12';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @input_complete, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = input_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end